classdef Segmenter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmenterUIFigure          matlab.ui.Figure
        PreProcessingPanel         matlab.ui.container.Panel
        ShadowButton               matlab.ui.control.Button
        SmoothButton               matlab.ui.control.Button
        SegmentationPanel          matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        RegionTab                  matlab.ui.container.Tab
        ThresholdButton            matlab.ui.control.Button
        PhasesEditFieldLabel       matlab.ui.control.Label
        PhasesEditField            matlab.ui.control.NumericEditField
        BoundaryTab                matlab.ui.container.Tab
        BordersButton              matlab.ui.control.Button
        ThresholdEditFieldLabel    matlab.ui.control.Label
        ThresholdEditField         matlab.ui.control.NumericEditField
        PatternTab                 matlab.ui.container.Tab
        FindPatternButton          matlab.ui.control.Button
        SensitivityEditFieldLabel  matlab.ui.control.Label
        SensitivityEditField       matlab.ui.control.NumericEditField
        PostProcessingPanel        matlab.ui.container.Panel
        IslandSizeEditFieldLabel   matlab.ui.control.Label
        IslandSizeEditField        matlab.ui.control.NumericEditField
        pixelsLabel                matlab.ui.control.Label
        IslandsButton              matlab.ui.control.Button
        LoadedImageDisplay         matlab.ui.control.UIAxes
        FilePanel                  matlab.ui.container.Panel
        OpenButton                 matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        FigureButton               matlab.ui.control.Button
        UndoButton                 matlab.ui.control.Button
        OriginalButton             matlab.ui.control.Button
    end


    properties (Access = private)
        LoadedImage = [];
        LoadedImageSample = [];
        Original = [];
        LoadMode = [];
        undoImage = [];
    end

    methods (Access = private)
    
        function updatedisplay(app)
            imagesc(app.LoadedImageDisplay,app.LoadedImageSample);
            axis(app.LoadedImageDisplay, 'image');
            axis(app.LoadedImageDisplay, 'off');
            colormap(app.LoadedImageDisplay,'gray');
        end
        
    end


    methods (Access = private)

        % Button pushed function: BordersButton
        function BordersButtonPushed(app, event)
            app.BordersButton.Enable = 'off';
            
            app.undoImage = app.LoadedImage;
            
            edgefilter = [ 0  1  0;...
                           1 -4  1;...
                           0  1  0];
            
            switch app.LoadMode 
                
                case 'single'
            
                    current = app.LoadedImage;
                    if size(current,3) == 3
                        current = rgb2gray(current);
                    elseif size(current,3) > 1 && size(current,3) ~= 3
                        current = mean(current,3);
                    end
                    
                    edge = imfilter(current,edgefilter,'circular');
                    edge = abs(edge) > app.ThresholdEditField.Value;
                    
                    app.LoadedImage = double(edge);
                    app.LoadedImageSample = double(edge);
                    
                case 'batch'
                    
                    for ii = 1:length(app.LoadedImage) 
                        current = app.LoadedImage{ii};
                        if size(current,3) == 3
                            current = rgb2gray(current);
                        elseif size(current,3) > 1 && size(current,3) ~= 3
                            current = mean(current,3);
                        end
                        
                        edge = imfilter(current,edgefilter,'circular');
                        edge = abs(edge) > app.ThresholdEditField.Value;
                        
                        app.LoadedImage{ii} = double(edge);
                    end
                    
                    app.LoadedImageSample = app.LoadedImage{1};
                    
            end
            
            updatedisplay(app);
            app.BordersButton.Enable = 'on';
        end

        % Button pushed function: FigureButton
        function FigureButtonPushed(app, event)
            h1 = figure;
            imagesc(app.LoadedImageSample);
            axis 'image';
            axis 'off';
            colormap 'gray';
        end

        % Button pushed function: FindPatternButton
        function FindPatternButtonPushed(app, event)
            app.undoImage = app.LoadedImage;
            app.FindPatternButton.Enable = 'off';
            switch app.LoadMode 
                
                case 'single'
                    
                    current = app.LoadedImage;
                    if size(current,3) == 3
                        current = rgb2gray(current);
                    elseif size(current,3) > 1 && size(current,3) ~= 3
                        current = mean(current,3);
                    end
                    
                    h1 = figure;
                    imagesc(current);
                    axis 'image';
                    axis 'off';
                    colormap 'gray';
                    
                    ROISelect = imrect;
                    inds = round(ROISelect.getPosition);
                    close(h1)
                    
                    template = current(inds(2):inds(2)+inds(4),inds(1):inds(1)+inds(3));
                    C = normxcorr2(template,current);
                    
                    app.LoadedImage = C > app.SensitivityEditField.Value;
                    app.LoadedImageSample = app.LoadedImage;
                    
                    updatedisplay(app);
                    
               case 'batch'
                    
                    current = app.LoadedImage{1};
                    if size(current,3) == 3
                        current = rgb2gray(current);
                    elseif size(current,3) > 1 && size(current,3) ~= 3
                        current = mean(current,3);
                    end
                    
                    h1 = figure;
                    imagesc(current);
                    axis 'image';
                    axis 'off';
                    colormap 'gray';
                    
                    ROISelect = imrect;
                    inds = round(ROISelect.getPosition);
                    close(h1)
                    
                    template = current(inds(2):inds(2)+inds(4),inds(1):inds(1)+inds(3));
                    C = normxcorr2(template,current);
                    
                    app.LoadedImage{1} = C > app.SensitivityEditField.Value;
                    app.LoadedImageSample = app.LoadedImage{1};
                    
                    for ii = 2:length(app.LoadedImage) 
                        current = app.LoadedImage{ii};
                        if size(current,3) == 3
                            current = rgb2gray(current);
                        elseif size(current,3) > 1 && size(current,3) ~= 3
                            current = mean(current,3);
                        end
                        
                        C = normxcorr2(template,current);
                        app.LoadedImage{ii} = C > app.SensitivityEditField.Value;
                    end
                    
                    updatedisplay(app);
            end
            app.FindPatternButton.Enable = 'on';
        end

        % Button pushed function: IslandsButton
        function IslandsButtonPushed(app, event)
            
            if ~isempty(setdiff(unique(app.LoadedImage), [0 1]))
                errordlg('Only Supported in Binary Images');
            else
                
                app.undoImage = app.LoadedImage;
                app.IslandsButton.Enable = 'off';
            
                switch app.LoadMode 
                    
                    case 'single'
                        current = app.LoadedImage;
                        current = bwareaopen(current,app.IslandSizeEditField.Value);
                        app.LoadedImage = double(current);
                        app.LoadedImageSample = double(current);
                        
                    case 'batch'
                        for ii = 1:length(app.LoadedImage)
                            current = app.LoadedImage{ii};
                            current = bwareaopen(current,app.IslandSizeEditField.Value);
                            app.LoadedImage{ii} = double(current);
                        end
                        app.LoadedImageSample = app.LoadedImage{1};
                end
                
                updatedisplay(app);
                app.IslandsButton.Enable = 'on';
                
            end
            
        end

        % Button pushed function: OpenButton
        function LoadButtonPushed(app, event)
            app.OpenButton.Enable = 'off';
            S = uiimport('-file');
            if isempty(S)
                
            else
                Snames = fieldnames(S);
                app.LoadedImage = S.(Snames{1});
            end
            
            if isnumeric(app.LoadedImage)
                app.LoadMode = 'single';
                app.LoadedImageSample = app.LoadedImage;
            elseif iscell(app.LoadedImage)
                app.LoadMode = 'batch';
                app.LoadedImageSample = app.LoadedImage{1};
            end
            
            app.Original = app.LoadedImageSample;
            
            updatedisplay(app);
            app.OpenButton.Enable = 'on';
        end

        % Button pushed function: OriginalButton
        function OriginalButtonPushed(app, event)
            h2 = figure;
            imagesc(app.Original);
            axis 'image';
            axis 'off';
            colormap 'gray';
        end

        % Button pushed function: ShadowButton
        function ShadowButtonPushed(app, event)
            app.undoImage = app.LoadedImage;
            app.ShadowButton.Enable = 'off';
            app.LoadedImage = Shadow(app.LoadedImage);
            
            switch app.LoadMode 
                
                case 'single'
                    app.LoadedImageSample = app.LoadedImage;
                    
                case 'batch'
                    app.LoadedImageSample = app.LoadedImage{1};
                    
            end
            
            updatedisplay(app);
            app.ShadowButton.Enable = 'on';
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            app.undoImage = app.LoadedImage;
            app.SmoothButton.Enable = 'off';
            
            switch app.LoadMode 
                
                case 'single'
                    app.LoadedImage = imgaussfilt(app.LoadedImage,1);
                    app.LoadedImageSample = app.LoadedImage;
                    
                case 'batch'
                    for ii = 1:length(app.LoadedImage)
                        app.LoadedImage{ii} = imgaussfilt(app.LoadedImage{ii},1);    
                    end
                    app.LoadedImageSample = app.LoadedImage{1};
                    
            end
            
            updatedisplay(app);
            app.SmoothButton.Enable = 'on';
        end

        % Button pushed function: ThresholdButton
        function ThresholdButtonPushed(app, event)
            app.undoImage = app.LoadedImage;
            app.ThresholdButton.Enable = 'off';
            
            switch app.LoadMode 
                
                case 'single'
            
                    current = app.LoadedImage;
                    if size(current,3) == 3
                        current = rgb2gray(current);
                    elseif size(current,3) > 1 && size(current,3) ~= 3
                        current = mean(current,3);
                    end
                    
                    threshes = multithresh(current,app.PhasesEditField.Value - 1);
                    threshes = [0, threshes];
                    
                    segmented = zeros(size(current));
                    
                    for jj = 1:(length(threshes)-1)
                        currentphase = (current >= threshes(jj)) & (current < threshes(jj+1));
                        segmented(currentphase) = jj;
                    end
                    
                    app.LoadedImage = segmented;
                    app.LoadedImageSample = segmented;
                    
                case 'batch'
                    
                    for ii = 1:length(app.LoadedImage) 
                        current = app.LoadedImage{ii};
                        if size(current,3) == 3
                            current = rgb2gray(current);
                        elseif size(current,3) > 1 && size(current,3) ~= 3
                            current = mean(current,3);
                        end
                        
                        threshes = multithresh(current,app.PhasesEditField.Value - 1);
                        threshes = [0, threshes];
                        
                        segmented = zeros(size(current));
                        
                        for jj = 1:(length(threshes)-1)
                            currentphase = (current >= threshes(jj)) & (current < threshes(jj+1));
                            segmented(currentphase) = jj;
                        end
                        
                        app.LoadedImage{ii} = segmented;
                    end
                    
                    app.LoadedImageSample = app.LoadedImage{1};
                    
            end
            
            updatedisplay(app);
            if app.PhasesEditField.Value > 2
                colormap(app.LoadedImageDisplay,'jet');
            end
            app.ThresholdButton.Enable = 'on';
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            app.LoadedImage = app.undoImage;       
            
            switch app.LoadMode 
                
                case 'single'
                    app.LoadedImageSample = app.undoImage;
                    
                case 'batch'
                    app.LoadedImageSample = app.undoImage{1};
                    
            end
            
            updatedisplay(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            current = app.LoadedImage;
            uisave('current');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmenterUIFigure = uifigure;
            app.SegmenterUIFigure.Name = 'Segmenter';
            app.PreProcessingPanel = uipanel(app.SegmenterUIFigure);
            app.PreProcessingPanel.Title = 'Pre-Processing';
            app.ShadowButton = uibutton(app.PreProcessingPanel, 'push');
            app.ShadowButton.ButtonPushedFcn = createCallbackFcn(app, @ShadowButtonPushed, true);
            app.ShadowButton.Icon = 'Shadow.png';
            app.ShadowButton.Text = 'Shadow';
            app.SmoothButton = uibutton(app.PreProcessingPanel, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Icon = 'Smooth.png';
            app.SmoothButton.Text = 'Smooth';
            app.SegmentationPanel = uipanel(app.SegmenterUIFigure);
            app.SegmentationPanel.Title = 'Segmentation';
            app.TabGroup = uitabgroup(app.SegmentationPanel);
            app.RegionTab = uitab(app.TabGroup);
            app.RegionTab.Title = 'Region';
            app.ThresholdButton = uibutton(app.RegionTab, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdButtonPushed, true);
            app.ThresholdButton.Icon = 'Otsu.png';
            app.ThresholdButton.Text = 'Threshold';
            app.PhasesEditFieldLabel = uilabel(app.RegionTab);
            app.PhasesEditFieldLabel.Text = '# Phases';
            app.PhasesEditField = uieditfield(app.RegionTab, 'numeric');
            app.PhasesEditField.Limits = [2 Inf];
            app.PhasesEditField.Value = 2;
            app.BoundaryTab = uitab(app.TabGroup);
            app.BoundaryTab.Title = 'Boundary';
            app.BordersButton = uibutton(app.BoundaryTab, 'push');
            app.BordersButton.ButtonPushedFcn = createCallbackFcn(app, @BordersButtonPushed, true);
            app.BordersButton.Icon = 'Edge.png';
            app.BordersButton.Text = 'Borders';
            app.ThresholdEditFieldLabel = uilabel(app.BoundaryTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.BoundaryTab, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.PatternTab = uitab(app.TabGroup);
            app.PatternTab.Title = 'Pattern';
            app.FindPatternButton = uibutton(app.PatternTab, 'push');
            app.FindPatternButton.ButtonPushedFcn = createCallbackFcn(app, @FindPatternButtonPushed, true);
            app.FindPatternButton.Icon = 'Pattern.png';
            app.FindPatternButton.Text = 'Find Pattern';
            app.SensitivityEditFieldLabel = uilabel(app.PatternTab);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.PatternTab, 'numeric');
            app.SensitivityEditField.Limits = [0 1];
            app.SensitivityEditField.Value = 0.8;
            app.PostProcessingPanel = uipanel(app.SegmenterUIFigure);
            app.PostProcessingPanel.Title = 'Post-Processing';
            app.IslandSizeEditFieldLabel = uilabel(app.PostProcessingPanel);
            app.IslandSizeEditFieldLabel.Text = 'Island Size';
            app.IslandSizeEditField = uieditfield(app.PostProcessingPanel, 'numeric');
            app.IslandSizeEditField.Limits = [0 Inf];
            app.IslandSizeEditField.ValueDisplayFormat = '%.0f';
            app.IslandSizeEditField.Value = 10;
            app.pixelsLabel = uilabel(app.PostProcessingPanel);
            app.pixelsLabel.Text = 'pixels';
            app.IslandsButton = uibutton(app.PostProcessingPanel, 'push');
            app.IslandsButton.ButtonPushedFcn = createCallbackFcn(app, @IslandsButtonPushed, true);
            app.IslandsButton.Icon = 'Islands.png';
            app.IslandsButton.Text = 'Islands';
            app.LoadedImageDisplay = uiaxes(app.SegmenterUIFigure);
            app.LoadedImageDisplay.XTick = [];
            app.LoadedImageDisplay.YTick = [];
            app.FilePanel = uipanel(app.SegmenterUIFigure);
            app.FilePanel.Title = 'File';
            app.OpenButton = uibutton(app.FilePanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.OpenButton.Icon = 'open_folder_blue-512.png';
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.FilePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save.png';
            app.SaveButton.Text = 'Save';
            app.FigureButton = uibutton(app.SegmenterUIFigure, 'push');
            app.FigureButton.ButtonPushedFcn = createCallbackFcn(app, @FigureButtonPushed, true);
            app.FigureButton.Icon = 'Figure.PNG';
            app.FigureButton.Text = 'Figure';
            app.UndoButton = uibutton(app.SegmenterUIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Icon = 'Undo_Arrow-512.png';
            app.UndoButton.Text = 'Undo';
            app.OriginalButton = uibutton(app.SegmenterUIFigure, 'push');
            app.OriginalButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalButtonPushed, true);
            app.OriginalButton.Icon = 'Figure.PNG';
            app.OriginalButton.Text = 'Original';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Segmenter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegmenterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmenterUIFigure)
        end
    end
end